classdef F1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        HomeTab                matlab.ui.container.Tab
        UPLOADVIDEOButton      matlab.ui.control.Button
        FUCKSHITUPButton       matlab.ui.control.Button
        EXITButton             matlab.ui.control.Button
        UPLOADEXCELDATAButton  matlab.ui.control.Button
        VideoTestTab           matlab.ui.container.Tab
        VideoTestButton        matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        KinematicsTab          matlab.ui.container.Tab
        KinematicsUIAxes1      matlab.ui.control.UIAxes
        KinematicsUIAxes2      matlab.ui.control.UIAxes
        KinematicsUIAxes4      matlab.ui.control.UIAxes
        KinematicsUIAxes3      matlab.ui.control.UIAxes
        ResetButton            matlab.ui.control.Button
        NumberofGraphsLabel    matlab.ui.control.Label
        KinematicsNumOfGraphs  matlab.ui.control.DropDown
        xDropDown              matlab.ui.control.DropDown
        TextKinematicsUIAxes1  matlab.ui.control.TextArea
        yDropDown              matlab.ui.control.DropDown
        PlotButton             matlab.ui.control.Button
        RawDataTab             matlab.ui.container.Tab
        UIRawTable             matlab.ui.control.Table
        ResetRawData           matlab.ui.control.Button
        SaveRawDataButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: VideoTestButton
        function VideoTestButtonPushed(app, event)
            %x = '/Users/asadpasat/Desktop/testVideo.mov';
            %vid= VideoReader(x); 
            %vidFrame = readFrame(vid);
            %image(vidFrame,'Parent',app.UIAxes);
            
            
            %v = VideoReader('/Users/asadpasat/Desktop/testVideo.mov');
            %v.CurrentTime = 0;
            %app.UIAxes.Visible = 'off';
            %while hasFrame(v)
                %vidFrame = readFrame(v,'native');
                %image(vidFrame,'Parent',app.UIAxes);
   
               % pause(1/40);
           %end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % --------- KINEMTAICS TAB -------
            % TURN OFF ALL KINEMATICS AXES
            app.KinematicsUIAxes1.Visible = 'off';
            app.KinematicsUIAxes2.Visible = 'off';
            app.KinematicsUIAxes3.Visible = 'off';
            app.KinematicsUIAxes4.Visible = 'off';
            %app.KinematicsNumOfGraphs = '0';
            
            
        end

        % Button pushed function: UPLOADVIDEOButton
        function UPLOADVIDEOButtonPushed(app, event)
            
            % --------- KINEMATICS TAB ------------
            % TURN OFF ALL KINEMATICS AXES
            app.KinematicsUIAxes1.Visible = 'off';
            app.KinematicsUIAxes2.Visible = 'off';
            app.KinematicsUIAxes3.Visible = 'off';
            app.KinematicsUIAxes4.Visible = 'off';
            
            % TURN OFF THE VARIABLE SELECTION FOR AXES 1
            app.xDropDown.Visible = 'off';
            app.yDropDown.Visible = 'off';
            app.TextKinematicsUIAxes1.Visible = 'off';
            % --------- END OF KINEMATICS TAB -------
        end

        % Button pushed function: UPLOADEXCELDATAButton
        function UPLOADEXCELDATAButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)
                msbox('Input an Excel file motherfucker')
            else
                t = readtable(fullfile(path,file));
                app.UIRawTable.Data = t;
                app.xDropDown.Items = t.Properties.VariableNames;
                app.yDropDown.Items = t.Properties.VariableNames;


            end
        end

        % Button pushed function: ResetRawData
        function ResetRawDataPushed(app, event)
          set(app.UIRawTable, 'Data', {});
        end

        % Value changed function: KinematicsNumOfGraphs
        function KinematicsNumOfGraphsValueChanged(app, event)

            numKinGraphs = app.KinematicsNumOfGraphs.Value;
           
            if numKinGraphs == '0'
                app.KinematicsUIAxes1.Visible = 'off';
                app.KinematicsUIAxes2.Visible = 'off';
                app.KinematicsUIAxes3.Visible = 'off';
                app.KinematicsUIAxes4.Visible = 'off';   
                app.xDropDown.Visible = 'off';
                app.yDropDown.Visible = 'off';
                app.TextKinematicsUIAxes1.Visible = 'off';
                
            end
          
            if numKinGraphs == '1'
                app.KinematicsUIAxes1.Visible = 'on';
                app.KinematicsUIAxes2.Visible = 'off';
                app.KinematicsUIAxes3.Visible = 'off';
                app.KinematicsUIAxes4.Visible = 'off';
                app.xDropDown.Visible = 'on';
                app.yDropDown.Visible = 'on';
                app.TextKinematicsUIAxes1.Visible = 'on';
               
            end
            
            if numKinGraphs == '2'
               app.KinematicsUIAxes1.Visible = 'on';
               app.KinematicsUIAxes2.Visible = 'on';
               app.KinematicsUIAxes3.Visible = 'off';
               app.KinematicsUIAxes4.Visible = 'off';
            end
             
            if numKinGraphs == '3'
               app.KinematicsUIAxes1.Visible = 'on';
               app.KinematicsUIAxes2.Visible = 'on';
               app.KinematicsUIAxes3.Visible = 'on';
               app.KinematicsUIAxes4.Visible = 'off';
            end
             
            if numKinGraphs == '4'
               app.KinematicsUIAxes1.Visible = 'on';
               app.KinematicsUIAxes2.Visible = 'on';
               app.KinematicsUIAxes3.Visible = 'on';
               app.KinematicsUIAxes4.Visible = 'on';
            end
        end

        % Value changed function: xDropDown
        function xDropDownValueChanged(app, event)
            value = app.xDropDown.Value;
            
           % plot(app.KinematicsUIAxes1, app.UIRawTable.Data(app.xDropDown.value),app.UIRawTable.Data.(app.yDropDown.Value));
            
            
        end

        % Value changed function: yDropDown
        function yDropDownValueChanged(app, event)
            value = app.yDropDown.Value;
            
            %plot(app.KinematicsUIAxes1, app.UIRawTable.Data.(app.xDropDown.Value),app.UIRawTable.Data.(app.yDropDown.Value));
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
              
                plot(app.KinematicsUIAxes1, app.UIRawTable.Data.(app.xDropDown.Value),app.UIRawTable.Data.(app.yDropDown.Value));
        end

        % Button pushed function: SaveRawDataButton
        function SaveRawDataButtonPushed(app, event)
            
            writetable(app.UIRawTable.Data, 'C:\Users\cisco\Desktop\matlabRawDataSaveButton.xlsx')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.UPLOADVIDEOButton = uibutton(app.HomeTab, 'push');
            app.UPLOADVIDEOButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADVIDEOButtonPushed, true);
            app.UPLOADVIDEOButton.Text = 'UPLOAD VIDEO';
            app.FUCKSHITUPButton = uibutton(app.HomeTab, 'push');
            app.FUCKSHITUPButton.Text = 'FUCK SHIT UP';
            app.EXITButton = uibutton(app.HomeTab, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.UPLOADEXCELDATAButton = uibutton(app.HomeTab, 'push');
            app.UPLOADEXCELDATAButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADEXCELDATAButtonPushed, true);
            app.UPLOADEXCELDATAButton.Text = 'UPLOAD EXCEL DATA';
            app.VideoTestTab = uitab(app.TabGroup);
            app.VideoTestTab.Title = 'Video Test';
            app.VideoTestButton = uibutton(app.VideoTestTab, 'push');
            app.VideoTestButton.ButtonPushedFcn = createCallbackFcn(app, @VideoTestButtonPushed, true);
            app.VideoTestButton.Text = 'VideoTest';
            app.UIAxes = uiaxes(app.VideoTestTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.KinematicsUIAxes1 = uiaxes(app.KinematicsTab);
            title(app.KinematicsUIAxes1, 'Title')
            xlabel(app.KinematicsUIAxes1, 'X')
            ylabel(app.KinematicsUIAxes1, 'Y')
            app.KinematicsUIAxes2 = uiaxes(app.KinematicsTab);
            title(app.KinematicsUIAxes2, 'Title')
            xlabel(app.KinematicsUIAxes2, 'X')
            ylabel(app.KinematicsUIAxes2, 'Y')
            app.KinematicsUIAxes4 = uiaxes(app.KinematicsTab);
            title(app.KinematicsUIAxes4, 'Title')
            xlabel(app.KinematicsUIAxes4, 'X')
            ylabel(app.KinematicsUIAxes4, 'Y')
            app.KinematicsUIAxes3 = uiaxes(app.KinematicsTab);
            title(app.KinematicsUIAxes3, 'Title')
            xlabel(app.KinematicsUIAxes3, 'X')
            ylabel(app.KinematicsUIAxes3, 'Y')
            app.ResetButton = uibutton(app.KinematicsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.NumberofGraphsLabel = uilabel(app.KinematicsTab);
            app.NumberofGraphsLabel.Text = 'Number of Graphs';
            app.KinematicsNumOfGraphs = uidropdown(app.KinematicsTab);
            app.KinematicsNumOfGraphs.Items = {'0', '1', '2', '3', '4'};
            app.KinematicsNumOfGraphs.ValueChangedFcn = createCallbackFcn(app, @KinematicsNumOfGraphsValueChanged, true);
            app.KinematicsNumOfGraphs.Value = '0';
            app.xDropDown = uidropdown(app.KinematicsTab);
            app.xDropDown.Items = {};
            app.xDropDown.ValueChangedFcn = createCallbackFcn(app, @xDropDownValueChanged, true);
            app.xDropDown.Value = {};
            app.yDropDown = uidropdown(app.KinematicsTab);
            app.yDropDown.Items = {};
            app.yDropDown.ValueChangedFcn = createCallbackFcn(app, @yDropDownValueChanged, true);
            app.yDropDown.Value = {};
            app.PlotButton = uibutton(app.KinematicsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TextKinematicsUIAxes1 = uitextarea(app.KinematicsTab);
            app.TextKinematicsUIAxes1.Value = {'Vs.'};
            app.RawDataTab = uitab(app.TabGroup);
            app.RawDataTab.Title = 'Raw Data';
            app.UIRawTable = uitable(app.RawDataTab);
            app.UIRawTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UIRawTable.RowName = {};
            app.ResetRawData = uibutton(app.RawDataTab, 'push');
            app.ResetRawData.ButtonPushedFcn = createCallbackFcn(app, @ResetRawDataPushed, true);
            app.ResetRawData.Text = 'Reset';
            app.SaveRawDataButton = uibutton(app.RawDataTab, 'push');
            app.SaveRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRawDataButtonPushed, true);
            app.SaveRawDataButton.Text = 'Save Raw Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end